classdef gait_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab1                      matlab.ui.container.Tab
        UploadDataButton          matlab.ui.control.Button
        MessageTextArea           matlab.ui.control.TextArea
        TextfromMCULabel          matlab.ui.control.Label
        BluetoothConnectionPanel  matlab.ui.container.Panel
        StatusLamp                matlab.ui.control.Lamp
        StatusLabel               matlab.ui.control.Label
        DisconnectButton          matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        DeviceListDropDown        matlab.ui.control.DropDown
        DeviceListDropDownLabel   matlab.ui.control.Label
        ScanButton                matlab.ui.control.Button
        BatteryAxes               matlab.ui.control.UIAxes
        DataVisualizationTab      matlab.ui.container.Tab
        DatePicker                matlab.ui.control.DatePicker
        DateDatePickerLabel       matlab.ui.control.Label
        ViewDropDown              matlab.ui.control.DropDown
        DataRangeDropDownLabel    matlab.ui.control.Label
        StepAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        BLEDevice   % ble object
        DeviceTable % result of blelist command
        IsConnected = false
        TextChar    % The characteristic object
        DataTimer   % Timer to poll for data since 'subscribe' is missing
        SaveDir = '' % Directory for auto-named daily CSV files
        DataJustCleared = false % True after successful upload+SD clear; reset on disconnect
        BatteryFillRect % handle to the colored fill rectangle
        BatteryPctText % handle to the "XX%" text
        LastFigSize = [0 0]
        SizeWatchTimer
    end
    
    methods (Access = private)
        
        % Timer Callback: Reads and parses battery data every 0.1 seconds
        function pollData(app, ~, ~)
            try
                if ~app.IsConnected || isempty(app.TextChar)
                    return;
                end

                data = read(app.TextChar);
        
                if ~isempty(data)
                    msg = strtrim(string(char(data)));
        
                    % Parse CSV: "VBAT:3.901,PCT:82"
                    parts = strsplit(msg, ',');
        
                    if numel(parts) >= 2
                        vbat = str2double(extractAfter(parts{1}, ':'));
                        pct  = str2double(extractAfter(parts{2}, ':'));
        
                        % Update battery gauge
                        if ~isnan(pct)
                            app.drawBattery(pct);
                        end
        
                        % Update text area
                        if ~isnan(pct) && ~isnan(vbat)
                            timestamp = char(datetime("now", "Format", "HH:mm:ss"));
                            line1 = sprintf('[%s]  BAT: %.3f V (%d%%)', timestamp, vbat, int32(pct));
                            app.MessageTextArea.Value = {line1};
                            drawnow;
                        end
        
                    else
                        % Fallback: raw string
                        timestamp = char(datetime("now", "Format", "HH:mm:ss"));
                        app.MessageTextArea.Value = {sprintf('[%s] RAW: %s', timestamp, char(msg))};
                        drawnow;
                    end
                end
        
            catch ME
                fprintf('Timer Error: %s\n', ME.message);
            end
        end

        function handleSyncNotification(app, src, ~)
            try
                data = read(src);
                chunk = char(data);
        
                % Filter out battery reports that may sneak in
                chunk = regexprep(chunk, 'VBAT:[0-9.]+,PCT:[0-9]+', '');
        
                if isempty(chunk)
                    return;
                end
        
                if contains(chunk, 'EOF')
                    before = extractBefore(string(chunk), 'EOF');
                    app.SyncBuffer = [app.SyncBuffer, uint8(char(before))];
                    app.SyncDone = true;
                else
                    app.SyncBuffer = [app.SyncBuffer, uint8(chunk)];
                end
            catch
            end
        end

        % Draw a Windows/iOS-style horizontal battery bar into BatteryAxes.
        function drawBattery(app, pct)
            if isempty(app.BatteryAxes) || ~isvalid(app.BatteryAxes)
                return;
            end
            pct = max(0, min(100, pct));

            ax = app.BatteryAxes;

            % First-time setup: draw the static chrome once
            if isempty(app.BatteryFillRect) || ~isvalid(app.BatteryFillRect)
                cla(ax);
                hold(ax, 'on');
                axis(ax, 'off');
                axis(ax, 'equal');
                xlim(ax, [0 12]);
                ylim(ax, [0 5]);

                % Body outline
                plot(ax, [0.3 10 10 0.3 0.3], [1 1 4 4 1], ...
                    'k-', 'LineWidth', 2);

                % Positive terminal nub
                rectangle(ax, 'Position', [10 2.1 0.7 0.8], ...
                    'FaceColor', [0 0 0], 'EdgeColor', 'none');

                % Fill rectangle — created once with zero width, updated below
                app.BatteryFillRect = rectangle(ax, ...
                    'Position', [0.5 1.2 0.01 2.6], ...
                    'FaceColor', [0.2 0.75 0.3], 'EdgeColor', 'none');

                % Percentage label — created once, updated below
                app.BatteryPctText = text(ax, 5.15, 2.5, '', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'FontSize', 14, 'FontWeight', 'bold', 'Color', [0 0 0]);

                hold(ax, 'off');
            end

            % Incremental update: only the fill width/color and the text
            if pct > 50
                fillColor = [0.20 0.75 0.30];   % green
            elseif pct > 20
                fillColor = [1.00 0.75 0.00];   % amber
            else
                fillColor = [0.90 0.20 0.20];   % red
            end

            innerX = 0.5;  innerY = 1.2;  innerW = 9.3;  innerH = 2.6;
            fillW  = max(0.01, innerW * (pct / 100));

            app.BatteryFillRect.Position  = [innerX innerY fillW innerH];
            app.BatteryFillRect.FaceColor = fillColor;
            app.BatteryPctText.String     = sprintf('%d%%', round(pct));
        end

        % Hide battery graphics and clear the message area.
        % Called on startup, disconnect, and failed connect.
        function resetDisconnectedUI(app)
            if ~isempty(app.BatteryAxes) && isvalid(app.BatteryAxes)
                cla(app.BatteryAxes);
                app.BatteryAxes.Visible = 'off';
                title(app.BatteryAxes, '');
            end
            app.BatteryFillRect = [];
            app.BatteryPctText  = [];

            if ~isempty(app.MessageTextArea) && isvalid(app.MessageTextArea)
                app.MessageTextArea.Value = {''};
            end
        end

        % --- Persist SaveDir across MATLAB restarts ---------------------
        function loadSaveDirPref(app)
            if ispref('GaitAnalysis', 'SaveDir')
                d = getpref('GaitAnalysis', 'SaveDir');
                if ischar(d) && isfolder(d)
                    app.SaveDir = d;
                end
            end
        end

        function saveDirPref(app)
            if ~isempty(app.SaveDir) && isfolder(app.SaveDir)
                setpref('GaitAnalysis', 'SaveDir', app.SaveDir);
            end
        end

        % --- Read per-day stats from the daily CSV ----------------------
        function s = readDayStats(app, dt)
            s = struct('correct',0, 'incorrect',0, 'total',0, ...
                       'pctCorrect',0, 'hasFile',false);
            if isempty(app.SaveDir) || ~isfolder(app.SaveDir)
                return;
            end
            fname = sprintf('gait_%s.csv', datestr(dt, 'yyyy-mm-dd'));
            fullPath = fullfile(app.SaveDir, fname);
            if ~isfile(fullPath), return; end
            try
                tbl = readtable(fullPath, 'TextType', 'string');
                types = tbl.step_type;
                s.correct   = sum(types == "CORRECT");
                s.incorrect = sum(types == "INCORRECT");
                s.total     = s.correct + s.incorrect;
                if s.total > 0
                    s.pctCorrect = 100 * s.correct / s.total;
                end
                s.hasFile = true;
            catch
            end
        end

        % --- Draw one Apple-style ring ---------------------------------
        function drawRing(app, ax, cx, cy, rOut, rIn, pct, color)
            nSeg  = 180;
            theta = linspace(0, 2*pi, nSeg);

            % Grey background ring (full 360)
            xb = [cx + rOut*cos(theta), cx + fliplr(rIn*cos(theta))];
            yb = [cy + rOut*sin(theta), cy + fliplr(rIn*sin(theta))];
            patch(ax, xb, yb, [0.88 0.88 0.88], 'EdgeColor','none');

            % Colored arc, starts at top, sweeps clockwise
            pctC = max(0, min(100, pct));
            if pctC > 0
                frac = pctC / 100;
                n = max(2, round(nSeg * frac));
                t2 = linspace(pi/2, pi/2 - 2*pi*frac, n);
                xf = [cx + rOut*cos(t2), cx + fliplr(rIn*cos(t2))];
                yf = [cy + rOut*sin(t2), cy + fliplr(rIn*sin(t2))];
                patch(ax, xf, yf, color, 'EdgeColor','none');
            end
        end

        % --- Top-level: decide which view to draw ----------------------
        function refreshVisualization(app)
            if isempty(app.StepAxes) || ~isvalid(app.StepAxes), return; end

            % Resolve anchor date (datepicker can be NaT on first run)
            dt = app.DatePicker.Value;
            if isempty(dt) || ~isdatetime(dt) || isnat(dt)
                dt = datetime('today');
                app.DatePicker.Value = dt;
            end
            dt = dateshift(dt, 'start', 'day');

            switch app.ViewDropDown.Value
                case 'Day',   app.drawDayView(dt);
                case 'Week',  app.drawWeekView(dt);
                case 'Month', app.drawMonthView(dt);
            end
        end

        % --- Day view: one big ring ------------------------------------
        function drawDayView(app, dt)
            ax = app.StepAxes;
            cla(ax); hold(ax, 'on');
            axis(ax, 'off'); axis(ax, 'equal');
            xlim(ax, [-1.5 1.5]); ylim(ax, [-1.5 1.5]);
            title(ax, sprintf('%s', datestr(dt, 'ddd, mmm dd, yyyy')));

            s = app.readDayStats(dt);
            ringColor = [0.15 0.78 0.35];   % "fitness green"

            app.drawRing(ax, 0, 0, 1.0, 0.75, s.pctCorrect, ringColor);

            if ~s.hasFile
                text(ax, 0, 0, 'No data', ...
                    'HorizontalAlignment','center','VerticalAlignment','middle',...
                    'FontSize',16,'Color',[0.45 0.45 0.45]);
            else
                text(ax, 0, 0.12, sprintf('%d%%', round(s.pctCorrect)), ...
                    'HorizontalAlignment','center','VerticalAlignment','middle',...
                    'FontSize',26,'FontWeight','bold');
                text(ax, 0, -0.12, sprintf('%d of %d correct', s.correct, s.total), ...
                    'HorizontalAlignment','center','VerticalAlignment','middle',...
                    'FontSize',11,'Color',[0.25 0.25 0.25]);
                text(ax, 0, -1.25, sprintf('%d incorrect', s.incorrect), ...
                    'HorizontalAlignment','center','FontSize',10,...
                    'Color',[0.6 0.25 0.25]);
            end
            hold(ax, 'off');
        end

        % --- Week view: 7 rings in a row -------------------------------
        function drawWeekView(app, dt)
            ax = app.StepAxes;
            cla(ax); hold(ax, 'on');
            axis(ax, 'off'); axis(ax, 'equal');
            xlim(ax, [0 7]); ylim(ax, [-1.2 2.2]);

            % Monday of the week containing dt (weekday: Sun=1..Sat=7)
            monday = dt - days(mod(weekday(dt) - 2, 7));
            title(ax, sprintf('Week of %s', datestr(monday, 'mmm dd, yyyy')));

            ringColor = [0.15 0.78 0.35];
            labels    = {'Mon','Tue','Wed','Thu','Fri','Sat','Sun'};
            totC = 0; totI = 0;

            for i = 0:6
                d  = monday + days(i);
                s  = app.readDayStats(d);
                totC = totC + s.correct;
                totI = totI + s.incorrect;

                cx = 0.5 + i;  cy = 0.9;
                app.drawRing(ax, cx, cy, 0.40, 0.27, s.pctCorrect, ringColor);

                text(ax, cx, 1.75, labels{i+1}, ...
                    'HorizontalAlignment','center','FontSize',10,'FontWeight','bold');
                text(ax, cx, 0.25, datestr(d, 'mm/dd'), ...
                    'HorizontalAlignment','center','FontSize',8,'Color',[0.3 0.3 0.3]);
                if s.hasFile
                    text(ax, cx, cy, sprintf('%d%%', round(s.pctCorrect)), ...
                        'HorizontalAlignment','center','VerticalAlignment','middle',...
                        'FontSize',9,'FontWeight','bold');
                end
            end

            tot = totC + totI;
            if tot > 0
                txt = sprintf('Week total: %d of %d correct (%d%%)', ...
                              totC, tot, round(100*totC/tot));
                col = [0.1 0.1 0.1];
            else
                txt = 'No data for this week';
                col = [0.45 0.45 0.45];
            end
            text(ax, 3.5, -0.7, txt, ...
                'HorizontalAlignment','center','FontSize',11,'Color',col);

            hold(ax, 'off');
        end

        % --- Month view: calendar grid of small rings ------------------
        function drawMonthView(app, dt)
            ax = app.StepAxes;
            cla(ax); hold(ax, 'on');
            axis(ax, 'off'); axis(ax, 'equal');
            xlim(ax, [0 7]); ylim(ax, [-1.2 7]);

            first = dateshift(dt, 'start', 'month');
            last  = dateshift(first, 'end', 'month');
            title(ax, sprintf('%s', datestr(first, 'mmmm yyyy')));

            ringColor = [0.15 0.78 0.35];
            labels    = {'Mon','Tue','Wed','Thu','Fri','Sat','Sun'};
            for c = 0:6
                text(ax, c+0.5, 6.5, labels{c+1}, ...
                    'HorizontalAlignment','center','FontSize',9,'FontWeight','bold');
            end

            col0  = mod(weekday(first) - 2, 7);  % 0 = Mon column
            nDays = day(last);
            totC = 0; totI = 0;

            for d = 1:nDays
                dd = first + days(d-1);
                s  = app.readDayStats(dd);
                totC = totC + s.correct;
                totI = totI + s.incorrect;

                idx = d - 1 + col0;
                col = mod(idx, 7);
                row = floor(idx / 7);
                cx  = col + 0.5;
                cy  = 5.5 - row;

                app.drawRing(ax, cx, cy, 0.40, 0.29, s.pctCorrect, ringColor);
                text(ax, cx, cy, sprintf('%d', d), ...
                    'HorizontalAlignment','center','VerticalAlignment','middle',...
                    'FontSize',8,'FontWeight','bold');
            end

            tot = totC + totI;
            if tot > 0
                txt = sprintf('Month total: %d of %d correct (%d%%)', ...
                              totC, tot, round(100*totC/tot));
                col = [0.1 0.1 0.1];
            else
                txt = 'No data for this month';
                col = [0.45 0.45 0.45];
            end
            text(ax, 3.5, -0.7, txt, ...
                'HorizontalAlignment','center','FontSize',11,'Color',col);

            hold(ax, 'off');
        end

        function applyFontScaling(app)
            refW = 653; refH = 308;
            pos = app.UIFigure.Position;
            scale = min(pos(3)/refW, pos(4)/refH);
            scale = max(0.6, min(scale, 3.0));
            scale = scale^0.12;
        
            fprintf('[scale] figure=%dx%d  scale=%.2f\n', ...
                round(pos(3)), round(pos(4)), scale);
        
            fontTable = {
                app.ScanButton,                12;
                app.ConnectButton,             12;
                app.DisconnectButton,          12;
                app.UploadDataButton,          14;
                app.DeviceListDropDown,        12;
                app.DeviceListDropDownLabel,   12;
                app.StatusLabel,               12;
                app.TextfromMCULabel,          12;
                app.MessageTextArea,           11;
                app.ViewDropDown,              12;
                app.DataRangeDropDownLabel,    12;
                app.DateDatePickerLabel,       12;
                app.DatePicker,                12;
                app.BluetoothConnectionPanel,  12;
            };
            for i = 1:size(fontTable,1)
                h = fontTable{i,1};
                target = fontTable{i,2} * scale;
                h.FontSize = target;   % NO try/catch — let errors surface
            end
        
            % Read back ScanButton's actual FontSize to confirm it stuck
            fprintf('[scale] ScanButton.FontSize=%.1f  (target=%.1f)\n', ...
                app.ScanButton.FontSize, 12*scale);
        
            app.BatteryAxes.FontSize = 11 * scale;
            app.StepAxes.FontSize    = 11 * scale;
            if ~isempty(app.BatteryPctText) && isvalid(app.BatteryPctText)
                app.BatteryPctText.FontSize = 14 * scale;
            end
        
            app.refreshVisualization();
        end

        function checkFigSize(app)
            p = app.UIFigure.Position;
            cur = [p(3) p(4)];
            if any(abs(cur - app.LastFigSize) > 2)
                app.LastFigSize = cur;
                app.applyFontScaling();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loadSaveDirPref();
            app.resetDisconnectedUI();
            app.refreshVisualization();

            app.DatePicker.Value = datetime('today');
            app.applyFontScaling();

            app.SizeWatchTimer = timer('ExecutionMode','fixedRate', ...
                           'Period', 0.2, ...
                           'TimerFcn', @(~,~)app.checkFigSize);
            start(app.SizeWatchTimer);
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.StatusLabel.Text = "Scanning...";
            app.StatusLamp.Color = [1 1 0]; 
            app.DeviceTable = blelist; 
            
            if isempty(app.DeviceTable)
                app.StatusLabel.Text = "No BLE devices found";
                app.StatusLamp.Color = [1 0 0]; 
                return;
            end
            
            % Populate dropdown
            items = strcat(app.DeviceTable.Name, " (", app.DeviceTable.Address, ")");
            app.DeviceListDropDown.Items = items;
            app.StatusLabel.Text = "Scan complete";
            app.StatusLamp.Color = [1 1 0];

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.DeviceListDropDown.Value), return; end
            
            app.BLEDevice = []; 
            app.TextChar = [];
            
            try
                selected = app.DeviceListDropDown.Value;
                addr = extractBetween(selected, "(", ")");
                
                app.StatusLabel.Text = "Connecting...";
                app.StatusLamp.Color = [1 1 0];
                drawnow;

                % 1. Connect
                app.BLEDevice = ble(char(addr{1}));
                
                % 2. CASCADE: Try to find the characteristic using multiple methods
                servUUID = '12345678-1234-5678-1234-56789ABCDEF0';
                charUUID = '12345678-1234-5678-1234-56789ABCDEF1';
                
                success = false;
                
                % METHOD A: The Standard 3-Argument (Service UUID, Char UUID)
                if ~success
                    try
                        app.TextChar = characteristic(app.BLEDevice, servUUID, charUUID);
                        success = true;
                        fprintf('Connected using Method A (Standard UUIDs)\n');
                    catch
                    end
                end
                
                % METHOD B: Using Names from the table (Generic Access)
                % Sometimes older versions prefer the string "Name" over UUID
                if ~success
                    try
                        % Try to find the names in the table
                        tbl = app.BLEDevice.Characteristics;
                        row = strcmpi(tbl.CharacteristicUUID, charUUID);
                        if any(row)
                             sName = tbl.ServiceName(row);
                             cName = tbl.CharacteristicName(row);
                             % Try passing the Names (as char vectors)
                             if iscell(sName), sName = sName{1}; end
                             if iscell(cName), cName = cName{1}; end
                             
                             app.TextChar = characteristic(app.BLEDevice, char(sName), char(cName));
                             success = true;
                             fprintf('Connected using Method B (Names)\n');
                        end
                    catch
                    end
                end

                % METHOD C: The "Row Index" or "Single UUID" fallback
                if ~success
                    try
                        % Some very specific versions allow just the Characteristic UUID
                        app.TextChar = characteristic(app.BLEDevice, charUUID);
                        success = true;
                        fprintf('Connected using Method C (Single UUID)\n');
                    catch
                    end
                end
                
                if ~success
                     error("Could not create Characteristic object. All syntax methods failed.");
                end
                
                app.IsConnected = true;
                app.StatusLabel.Text = "Connected!";
                app.StatusLamp.Color = [0 1 0];

                % Reset display on fresh connection
                app.BatteryAxes.Visible = 'on';
                title(app.BatteryAxes, 'Battery Percentage');
                app.drawBattery(0);
                app.MessageTextArea.Value = {''};
                
                % 3. Start Polling Timer (Since 'subscribe' is missing)
                if ~isempty(app.DataTimer)
                    stop(app.DataTimer);
                    delete(app.DataTimer);
                end
                
                % Create a timer that fires 10 times a second
                app.DataTimer = timer('ExecutionMode', 'fixedRate', ...
                                      'Period', 0.1, ...
                                      'TimerFcn', @(~,~)app.pollData);
                start(app.DataTimer);
                fprintf('Polling timer started.\n');
        
            catch ME 
                app.StatusLabel.Text = "Connection failed";
                app.StatusLamp.Color = [1 0 0];
                app.resetDisconnectedUI();
                fprintf('ERROR: %s\n', ME.message);
                
                % EMERGENCY DEBUG
                % If it fails, run this line in your Command Window to see the help docs:
                % help ble/characteristic
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.IsConnected = false;
            app.DataJustCleared = false; % Allow upload after re-connect + new recording

            % Stop the timer first!
            if ~isempty(app.DataTimer)
                stop(app.DataTimer); 
                delete(app.DataTimer);
                app.DataTimer = [];
            end

            app.TextChar = [];
            app.BLEDevice = [];

            app.resetDisconnectedUI();
    
            app.StatusLabel.Text = "Disconnected";
            app.StatusLamp.Color = [1 0 0];

        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            if ~app.IsConnected
                uialert(app.UIFigure, 'Not connected to device.', 'Error');
                return;
            end
        
            % Deny upload if SD was just cleared — no new sessions exist yet
            if app.DataJustCleared
                uialert(app.UIFigure, ...
                    'Data was just uploaded. Disconnect, record new sessions, then upload again.', ...
                    'No New Data');
                return;
            end

            % Stop battery polling during transfer
            if ~isempty(app.DataTimer)
                stop(app.DataTimer);
            end
        
            app.MessageTextArea.Value = {'Requesting data from device...'};
            drawnow;
        
            try
                allData = '';
                timeoutSec = 60;
                tStart = tic;
                chunkCount = 0;
        
                % Send SYNC — Arduino opens all session files and writes first chunk
                write(app.TextChar, uint8('SYNC'));
                pause(0.2);
        
                while toc(tStart) < timeoutSec
                    raw = read(app.TextChar);
                    chunk = char(raw);
                
                    % Filter stray battery reports
                    chunk = regexprep(chunk, 'VBAT:[0-9.]+,PCT:[0-9]+', '');
                
                    % [EOF] is the ONLY stop signal — do not break on partial chunks
                    % (partial chunks occur at the end of every session file, not just the last)
                    if contains(chunk, '[EOF]')
                        before = char(extractBefore(string(chunk), '[EOF]'));
                        if ~isempty(strtrim(before))
                            allData = [allData, before];
                        end
                        app.MessageTextArea.Value = {sprintf('Transfer complete! %d bytes in %d chunks', length(allData), chunkCount)};
                        drawnow;
                        break;
                    end
                
                    % Skip control command echoes (appear when MATLAB reads before Arduino writes)
                    chunkTrimmed = strtrim(chunk);
                    if strcmp(chunkTrimmed, 'NEXT') || strcmp(chunkTrimmed, 'SYNC') || isempty(chunkTrimmed)
                        write(app.TextChar, uint8('NEXT'));
                        pause(0.2);
                        continue;
                    end
                
                    % Valid data chunk — accumulate it
                    chunkCount = chunkCount + 1;
                    rawLen = length(raw);
                    allData = [allData, chunk];
                    app.MessageTextArea.Value = {sprintf('Receiving... %d bytes (chunk %d)', length(allData), chunkCount)};
                    drawnow;
                
                    % Send NEXT for all chunks.
                    % Small chunks (< 240) mark end of a session file — give Arduino extra time
                    % to open the next session file or write [EOF] before we read again.
                    write(app.TextChar, uint8('NEXT'));
                    if rawLen < 240
                        pause(0.4);  % Extra time: Arduino is advancing to next session or writing [EOF]
                    else
                        pause(0.2);
                    end
                end
        
                % Remove duplicate headers that appear between session files
                allData = regexprep(allData, 'timestamp_ms,step_type\s*[\r\n]+', '');
                % Ensure every data row ends with a newline (sessions may lack trailing newline)
                allData = regexprep(allData, '(CORRECT|INCORRECT)(?!\r?\n)', sprintf('$1\n'));


                if isempty(strtrim(allData))
                    % Arduino responded with no session files (nothing to transfer).
                    % Treat this the same as a just-cleared device: warn the user and
                    % lock out further uploads until they disconnect + record again.
                    app.DataJustCleared = true;
                    app.MessageTextArea.Value = {'No step data on device.'};
                    uialert(app.UIFigure, ...
                        'No data on device. Disconnect, record new sessions, then upload again.', ...
                        'No New Data');
                    % Restart battery polling before leaving early
                    if ~isempty(app.DataTimer) && strcmp(app.DataTimer.Running, 'off')
                        start(app.DataTimer);
                    end
                    return;
                else
                    % --- Auto-save to a dated daily file ---
        
                    % On first use (or if SaveDir was cleared), ask for a folder once
                    if isempty(app.SaveDir) || ~isfolder(app.SaveDir)
                        app.SaveDir = uigetdir(pwd, 'Select folder to save daily gait files');
                        if app.SaveDir == 0
                            app.SaveDir = '';
                            app.MessageTextArea.Value = {'Save cancelled. No folder selected.'};
                            if ~isempty(app.DataTimer) && strcmp(app.DataTimer.Running, 'off')
                                start(app.DataTimer);
                            end
                            return;
                        end

                        app.saveDirPref();
                    end
        
                    % Build dated filename: gait_YYYY-MM-DD.csv
                    dateStr  = char(datetime('now', 'Format', 'yyyy-MM-dd'));
                    fileName = sprintf('gait_%s.csv', dateStr);
                    fullPath = fullfile(app.SaveDir, fileName);
        
                    % Append if today's file exists, create fresh if not.
                    % If fopen fails (most commonly because Excel has the file open),
                    % warn the user and abort WITHOUT sending DONE — so nothing on
                    % the device gets deleted and they can retry after closing Excel.
                    if isfile(fullPath)
                        fid = fopen(fullPath, 'a');
                        if fid == -1
                            uialert(app.UIFigure, ...
                                sprintf(['Could not open daily file for writing:\n%s\n\n' ...
                                         'It is likely open in Excel or another program.\n' ...
                                         'Close it and press Upload Data again.'], fullPath), ...
                                'File Locked');
                            app.MessageTextArea.Value = {'Upload aborted: daily CSV is locked.'};
                            if ~isempty(app.DataTimer) && strcmp(app.DataTimer.Running, 'off')
                                start(app.DataTimer);
                            end
                            return;
                        end
                        fprintf(fid, '%s', allData);
                        fclose(fid);
                        app.MessageTextArea.Value = {
                            sprintf('Appended %d bytes to:', length(allData));
                            fullPath};
                    else
                        fid = fopen(fullPath, 'w');
                        if fid == -1
                            uialert(app.UIFigure, ...
                                sprintf(['Could not create daily file:\n%s\n\n' ...
                                         'Check folder permissions, then press Upload Data again.'], fullPath), ...
                                'File Error');
                            app.MessageTextArea.Value = {'Upload aborted: cannot write daily CSV.'};
                            if ~isempty(app.DataTimer) && strcmp(app.DataTimer.Running, 'off')
                                start(app.DataTimer);
                            end
                            return;
                        end
                        fprintf(fid, 'timestamp_ms,step_type\n');
                        fprintf(fid, '%s', allData);
                        fclose(fid);
                        app.MessageTextArea.Value = {
                            sprintf('Saved %d bytes to:', length(allData));
                            fullPath};
                    end
                    drawnow;
        
                    % Tell Arduino the save was successful — it will delete session files
                    pause(0.1);
                    write(app.TextChar, uint8('DONE'));
                    pause(0.3);
                    % Read [CLEARED] confirmation and lock out re-upload until next disconnect
                    try
                        clearedResp = char(read(app.TextChar));
                        if contains(clearedResp, '[CLEARED]')
                            app.DataJustCleared = true;
                        end
                    catch
                    end
                    app.MessageTextArea.Value = {
                        sprintf('Saved %d bytes to:', length(allData));
                        fullPath;
                        'Device SD card cleared.'};

                    app.refreshVisualization();
                    drawnow;
                end
        
            catch ME
                app.MessageTextArea.Value = {sprintf('Upload error: %s', ME.message)};
            end
        
            % Restart battery polling
            if ~isempty(app.DataTimer) && strcmp(app.DataTimer.Running, 'off')
                start(app.DataTimer);
            end
        end

        % Callback function
        function LoadPlotButtonPushed(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer before anything gets destroyed
            app.IsConnected = false;
            
            if ~isempty(app.DataTimer)
                try
                    stop(app.DataTimer);
                    delete(app.DataTimer);
                catch
                end
                app.DataTimer = [];
            end

            if ~isempty(app.SizeWatchTimer)
                try
                    stop(app.SizeWatchTimer); 
                    delete(app.SizeWatchTimer);
                catch
                end
            end
        
            % Clean up BLE
            app.TextChar = [];
            app.BLEDevice = [];
        
            delete(app.UIFigure)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged2(app, event)
            if app.TabGroup.SelectedTab == app.DataVisualizationTab
                app.refreshVisualization();
            end
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            app.refreshVisualization();            
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            app.refreshVisualization();
            
        end

        % Callback function
        function TabGroupSelectionChanged3(app, event)
            % accident
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            app.applyFontScaling();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged2, true);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Connection, Upload, & Battery';
            app.BatteryAxes = uiaxes(app.Tab1);
            title(app.BatteryAxes, 'Battery Percentage')
            xlabel(app.BatteryAxes, 'X')
            ylabel(app.BatteryAxes, 'Y')
            zlabel(app.BatteryAxes, 'Z')
            app.BatteryAxes.Visible = 'off';
            app.BluetoothConnectionPanel = uipanel(app.Tab1);
            app.BluetoothConnectionPanel.Title = 'Bluetooth Connection';
            app.ScanButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.DeviceListDropDownLabel = uilabel(app.BluetoothConnectionPanel);
            app.DeviceListDropDownLabel.Text = 'Device List';
            app.DeviceListDropDown = uidropdown(app.BluetoothConnectionPanel);
            app.DeviceListDropDown.Items = {'Scan for Devices'};
            app.DeviceListDropDown.Value = 'Scan for Devices';
            app.ConnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLabel = uilabel(app.BluetoothConnectionPanel);
            app.StatusLabel.Text = {'Status: '; 'Disconnected'};
            app.StatusLamp = uilamp(app.BluetoothConnectionPanel);
            app.TextfromMCULabel = uilabel(app.Tab1);
            app.TextfromMCULabel.Text = {'Status / '; 'Battery Voltage &'; 'Percentage'};
            app.MessageTextArea = uitextarea(app.Tab1);
            app.MessageTextArea.Editable = 'off';
            app.UploadDataButton = uibutton(app.Tab1, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.DataVisualizationTab = uitab(app.TabGroup);
            app.DataVisualizationTab.Title = 'Data Visualization';
            app.StepAxes = uiaxes(app.DataVisualizationTab);
            title(app.StepAxes, 'Step Classification Over Time')
            xlabel(app.StepAxes, 'Time (minutes)')
            ylabel(app.StepAxes, 'Steps')
            zlabel(app.StepAxes, 'Z')
            app.DataRangeDropDownLabel = uilabel(app.DataVisualizationTab);
            app.DataRangeDropDownLabel.Text = 'Data Range';
            app.ViewDropDown = uidropdown(app.DataVisualizationTab);
            app.ViewDropDown.Items = {'Day', 'Week', 'Month'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'Day';
            app.DateDatePickerLabel = uilabel(app.DataVisualizationTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DatePicker = uidatepicker(app.DataVisualizationTab);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker.Value = datetime([2026 4 1]);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gait_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end